classdef matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LastKnotClampingSwitch          matlab.ui.control.Switch
        LastKnotClampingSwitch_3Label   matlab.ui.control.Label
        FirstKnotClampingSwitch         matlab.ui.control.Switch
        FirstKnotClampingSwitch_3Label  matlab.ui.control.Label
        KnotVectorParametrizationDropDown  matlab.ui.control.DropDown
        KnotVectorParametrizationLabel  matlab.ui.control.Label
        GenerateKnotVectorButton        matlab.ui.control.Button
        EditField2                      matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        PlotButton                      matlab.ui.control.Button
        NoOfKnotsm1Label                matlab.ui.control.Label
        NoOfDataPointsn1Label           matlab.ui.control.Label
        SelectDataPointsButton          matlab.ui.control.Button
        OrderpEditField                 matlab.ui.control.NumericEditField
        OrderpEditFieldLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        p % Order
        P % Set of datapoints
        ee % Parametrization exponent
        fk % First knot clamping flag
        lk % Last knot clamping flag
        T % Knot vector
        m % No. of knots - 1
        n % No. of data points - 1
    end
    
    methods (Access = private)


        function[val] = nb_spline_basis(app,m,i,T,t)
                
                % m: Order of the B-spline
                % i: Index of the last knot over which nb_spline_basis(m,i,T,t) stands
                % T: Knot Vector
                % t: Parameter Value at which the function is to be evaluated
                
                % Case1- m=1
                if m==1
                    if(t>=T(i-1)&&t<T(i))
                        val=1;
                    else
                        val=0;
                    end
                    
                    % Case2- m>1
                else
                    if m>1
                        % d=(t-T(i-m))/(T(i-1)-T(i-m));
                        % e=(T(i)-t)/(T(i)-T(i-m+1));
                        
                        % compute d
                        if T(i-1)~= T(i-m)
                            d=(t-T(i-m))/(T(i-1)-T(i-m));
                        else
                            d=0;
                        end
                        
                        % compute e
                        if T(i) ~= T(i-m+1)
                            e=(T(i)-t)/(T(i)-T(i-m+1));
                        else
                            e=0;
                        end
                        
                        % Recursion relation incorporated
                        val = d*nb_spline_basis(app,m-1,i-1,T,t) + e*nb_spline_basis(app,m-1,i,T,t);
                    end
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OrderpEditField
        function OrderpEditFieldValueChanged(app, event)
            app.p = app.OrderpEditField.Value;
        end

        % Button pushed function: SelectDataPointsButton
        function SelectDataPointsButtonPushed(app, event)
            cla
            [dx,dy] = getpts;
            app.P = [dx,dy];
            
            app.n = size(app.P,1) - 1;
            
            app.EditField.Value = app.n + 1;
            
            app.m = app.n + app.p;
            
            app.EditField2.Value = app.m + 1;
           
        end

        % Value changed function: KnotVectorParametrizationDropDown
        function KnotVectorParametrizationDropDownValueChanged(app, event)
            value = app.KnotVectorParametrizationDropDown.Value;
            
            if value == "Uniform"
                app.ee = 0.0;
            elseif value == "Chord Length"
                app.ee = 1.0;
            else
                app.ee = 0.5;
            end
        end

        % Value changed function: FirstKnotClampingSwitch
        function FirstKnotClampingSwitchValueChanged(app, event)
            value = app.FirstKnotClampingSwitch.Value;
           
            if value == "Off"
                app.fk = 0;
            else
                app.fk = 1;
            end
        end

        % Value changed function: LastKnotClampingSwitch
        function LastKnotClampingSwitchValueChanged(app, event)
            value = app.LastKnotClampingSwitch.Value;
            
            if value == "Off"
                app.lk = 0;
            else
                app.lk = 1;
            end
        end

        % Button pushed function: GenerateKnotVectorButton
        function GenerateKnotVectorButtonPushed(app, event)
            % Parametrization
            value = app.KnotVectorParametrizationDropDown.Value;
            
            if value == "Uniform"
                app.ee = 0.0;
            elseif value == "Chord Length"
                app.ee = 1.0;
            else
                app.ee = 0.5;
            end


            % First knot clamping
            value = app.FirstKnotClampingSwitch.Value;
           
            if value == "Off"
                app.fk = 0;
            else
                app.fk = 1;
            end


            % Last knot Clamping    
            value = app.LastKnotClampingSwitch.Value;
            
            if value == "Off"
                app.lk = 0;
            else
                app.lk = 1;
            end


            % Generating n+1 parameter values
                
                u(size(app.P),1) = 0;
                u(1) = 0;
                for i = 1:(size(app.P,1)-1)
                    u(i+1) = u(i) + (norm(app.P(i+1,:) - app.P(i,:)))^app.ee;
                end
                
                sum_u = sum(u);
                u = u/max(u);
                
                
                app.T = zeros(size(app.P,1)+app.p,1);
                
                % Assuming simple knots
                if app.fk==0 && app.lk==0
                    app.T([(app.p+1):(app.p+size(app.P,1))],1) = u;
                    
                    for i = app.p:-1:1
                        app.T(i) = app.T(i+1) - 1;
                    end
                end
                
                % Clamping at first data point
                if app.fk==1 && app.lk==0
                    app.T([(app.p+1):(app.p+size(app.P,1))],1) = u;
                    app.T(app.p) = app.T(app.p+1) - 1;
                    for i = app.p-1:-1:1
                        app.T(i) = app.T(i+1);
                    end
                end
                
                % Clamping at last data point
                if app.fk==0 && app.lk==1
                    app.T([1:size(app.P,1)],1) = u;
                    app.T(size(app.P,1)+1,1) = app.T(size(app.P,1),1) + 1;
                    for i = 2:app.p
                        app.T(size(app.P,1)+i,1) = app.T(size(app.P,1),1) + 1;
                    end
                end
                
                % Clamping at both first and last points
                if app.fk==1 && app.lk==1
                    % First p knots = 0
                    for i = 1:app.p
                        app.T(i) = 0;
                    end
                    
                    % Last p knots = 1
                    for i = 1:app.p
                        app.T(size(app.P,1)+i) = 1;
                    end
                    
                    % Remaining number of knots : size(P,1) + p - 2p
                    % Average p-1 parameter values at a time
                    
                    for i = 1:(size(app.P,1) + app.p - 2*app.p)
                        sump = 0;
                        for j = 1:(app.p-1)
                            sump = sump + u(i+j);
                        end
                        app.T(app.p+i,1) = sump/(app.p-1);
                    end
                end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            % Interval of full support- [a,b]
            a = app.T(app.p);
            b = app.T(app.m+1-app.p+1);
            
            % Generate a B-spline Curve
            for i=1:200
                t1 = (i-1)/199;
                t = (1-t1)*a + t1*b - 0.000001;
                
                x(i) = 0;
                y(i) = 0;
                
                for k = 1:app.n+1
                    x(i) = x(i) + nb_spline_basis(app,app.p,app.p+k,app.T,t)*app.P(k,1);
                    y(i) = y(i) + nb_spline_basis(app,app.p,app.p+k,app.T,t)*app.P(k,2);
                end
            end
            
%             hold on;
            
            % Plotting the B-spline curve
            axis=app.UIAxes;
            hold(axis,'on');
            % Plotting the B-spline curve
            plot(app.UIAxes,x,y,'r-','linewidth',2);
            plot(app.UIAxes,app.P(:,1),app.P(:,2),'m*','MarkerSize',16);
           
            
            % Plotting control polyline
            for i = 1:app.n
                line(app.UIAxes,[app.P(i,1) app.P(i+1,1)],[app.P(i,2) app.P(i+1,2)],'color','k','linewidth',3,'linestyle','--');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'B-Spline Curve')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OrderpEditFieldLabel = uilabel(app.UIFigure);
            app.OrderpEditFieldLabel.Text = 'Order (p)';
            app.OrderpEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderpEditField.ValueChangedFcn = createCallbackFcn(app, @OrderpEditFieldValueChanged, true);
            app.OrderpEditField.Value = 4;
            app.SelectDataPointsButton = uibutton(app.UIFigure, 'push');
            app.SelectDataPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataPointsButtonPushed, true);
            app.SelectDataPointsButton.Text = 'Select Data Points';
            app.NoOfDataPointsn1Label = uilabel(app.UIFigure);
            app.NoOfDataPointsn1Label.Text = 'No. Of Data Points (n+1):- ';
            app.NoOfKnotsm1Label = uilabel(app.UIFigure);
            app.NoOfKnotsm1Label.Text = 'No. Of Knots (m+1):- ';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 10;
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.GenerateKnotVectorButton = uibutton(app.UIFigure, 'push');
            app.GenerateKnotVectorButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateKnotVectorButtonPushed, true);
            app.GenerateKnotVectorButton.Text = 'Generate Knot Vector';
            app.KnotVectorParametrizationLabel = uilabel(app.UIFigure);
            app.KnotVectorParametrizationLabel.Text = {'Knot Vector'; 'Parametrization'};
            app.KnotVectorParametrizationDropDown = uidropdown(app.UIFigure);
            app.KnotVectorParametrizationDropDown.Items = {'Uniform', 'Chord Length', 'Centripetal'};
            app.KnotVectorParametrizationDropDown.ValueChangedFcn = createCallbackFcn(app, @KnotVectorParametrizationDropDownValueChanged, true);
            app.KnotVectorParametrizationDropDown.Value = 'Uniform';
            app.FirstKnotClampingSwitch_3Label = uilabel(app.UIFigure);
            app.FirstKnotClampingSwitch_3Label.Text = 'First Knot Clamping';
            app.FirstKnotClampingSwitch = uiswitch(app.UIFigure, 'slider');
            app.FirstKnotClampingSwitch.ValueChangedFcn = createCallbackFcn(app, @FirstKnotClampingSwitchValueChanged, true);
            app.LastKnotClampingSwitch_3Label = uilabel(app.UIFigure);
            app.LastKnotClampingSwitch_3Label.Text = 'Last Knot Clamping';
            app.LastKnotClampingSwitch = uiswitch(app.UIFigure, 'slider');
            app.LastKnotClampingSwitch.ValueChangedFcn = createCallbackFcn(app, @LastKnotClampingSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end